classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        TabGroup                     matlab.ui.container.TabGroup
        Tab                          matlab.ui.container.Tab
        DisplayResult                matlab.ui.control.Table
        userGuideLabel               matlab.ui.control.Label
        testLabel                    matlab.ui.control.Label
        Panel                        matlab.ui.container.Panel
        FlowrateQEditFieldLabel      matlab.ui.control.Label
        FlowrateQEditField           matlab.ui.control.NumericEditField
        ViscosityDropDownLabel       matlab.ui.control.Label
        ViscosityDropDown            matlab.ui.control.DropDown
        PressurePDropDownLabel       matlab.ui.control.Label
        PressurePDropDown            matlab.ui.control.DropDown
        RPMDropDownLabel             matlab.ui.control.Label
        RPMDropDown                  matlab.ui.control.DropDown
        FlowrateVariationEditField   matlab.ui.control.NumericEditField
        Label                        matlab.ui.control.Label
        ViscosityVariationEditField  matlab.ui.control.NumericEditField
        Label_2                      matlab.ui.control.Label
        PressureVariationEditField   matlab.ui.control.NumericEditField
        Label_3                      matlab.ui.control.Label
        dropdownmodeSwitch           matlab.ui.control.Switch
        dropdownmodeSwitchLabel      matlab.ui.control.Label
        TextFieldPanel               matlab.ui.container.Panel
        ViscosityEditFieldLabel      matlab.ui.control.Label
        ViscosityEditField           matlab.ui.control.NumericEditField
        PressurePEditFieldLabel      matlab.ui.control.Label
        PressurePEditField           matlab.ui.control.NumericEditField
        TotalresultsLabel            matlab.ui.control.Label
        mhrLabel                     matlab.ui.control.Label
        cStLabel                     matlab.ui.control.Label
        barLabel                     matlab.ui.control.Label
        UIAxes                       matlab.ui.control.UIAxes
        UITableResults               matlab.ui.control.Table
        SelectCurveDropDownLabel     matlab.ui.control.Label
        SelectCurveDropDown          matlab.ui.control.DropDown
        Tab2                         matlab.ui.container.Tab
    end

    
    properties (Access = private)
        data = load('data_sheet_all_mod.mat');
        data2 = load('data_2_sheet_all.mat');
        
        currentFlowrateData
        currentViscocityData
        currentPrassureData
        flowrate
        viscosity
        pressure
        flowrateVar = 0;
        viscosityVar = 0;
        pressureVar = 0;
        guideStr
        fl
        pr
        vi
        RPM
    end
    
    methods (Access = private)
        % find flowrate
        function results = findFlowrate(app,value,variation)
            % getting data from data_sheet_1.mat
            
            if variation == 0
                result = app.data.flowrate == app.flowrate;
                results  = app.data.df_n(result,:);
            else
                results = plusOrMinus(app,value,variation,1,app.data.df_n)
            end
        end
        
        function results = findViscocity(app,value,variation)
            if variation == 0
                result = app.currentFlowrateData(:,3)  == value;
                results = app.currentFlowrateData(result,:);
            else
                results = plusOrMinus(app,value,variation,3,app.currentFlowrateData)
            end
            %             % getting data from .mat
            %             result = find(app.currentFlowrateData(:,3)  == value);
            %             results = app.currentFlowrateData(result,:);
        end
        
        function results = findPressure(app,inp)
            if variation == 0
                result = find(app.currentViscocityData(:,4) == inp);
                results = app.currentViscocityData(result,:);
            else
                results = plusOrMinus(app,value,variation,4,app.currentViscocityData)
            end
        end
        
        function results = findFinalIndex(app,value,variation)
            if variation == 0
                result = find(app.currentViscocityData(:,4) == value);
                results = app.currentViscocityData(result,:);
            else
                results = plusOrMinus(app,value,variation,4,app.currentViscocityData)
            end
        end
        
        function flowratefunc(app,Data)
            app.currentFlowrateData = Data;
            app.viscosity = Data(1,3);
            app.pressure = Data(1,4);
            
            listData = unique(Data(:,3));
            
            viscodd = cellstr(num2str(listData))';
            app.ViscosityDropDown.Items = viscodd;
            app.ViscosityDropDown.Enable = 'on';
            
            app.DisplayResult.Visible = 'on';
            app.DisplayResult.Data = Data;
            
            app.userGuideLabel.Text = 'select viscocity';
            app.testLabel.Text = num2str(length(Data'));
            
            [col,~] = size(listData);
            
            f = @pressurefunc;
            
            viscocityfunc(app,Data,f);
        end
        
        function viscocityfunc(app,Data,f)
            app.currentViscocityData = Data;
            
            listData = unique(Data(:,4));
            app.PressurePDropDown.Items = cellstr(num2str(listData))';
            app.PressurePDropDown.Enable = 'on';
            [col , ~] = size(listData);
            f(app,Data);
            app.testLabel.Text = num2str(col);
            app.DisplayResult.Data = Data;
            
        end
        
        function pressurefunc(app,Data)
            app.currentPrassureData = Data;
            app.DisplayResult.Data = Data;
            app.testLabel.Text = 'done';
            index = Data(:,5);
            if ~isempty(index)
                app.userGuideLabel.Text = strcat('Diameter :',{' '},num2str(index(1)));
            end
        end
        
        function results = plusOrMinus(app,value,variation,colIndex,currentData)
            max =  value + variation;
            min = value - variation;
            if ~isempty(value)
                indexes = find(min < currentData(:,colIndex) & max > currentData(:,colIndex));
                disp(min+" "+max);
                results = currentData(indexes,:);
            else
                results = zeros(0,6);
            end
        end


        function totalResults(app,len)
            app.TotalresultsLabel.Text = strcat('Total results :',{' '},num2str(len));
            app.TotalresultsLabel.Visible = 'on';
            
            %             plotting data
            selected_data = app.DisplayResult.Data;
            ax = app.UIAxes;
            %             plot(ax,selected_data);
            %             legend(ax,'Flowrate','Power','viscosity','Pressure','Diameter','pitch');
        end
        
        %         data2 functions
        
        function result = calcQTH(app,RPM,fl)
            result = (1000 * fl) / RPM;
        end
        
        function result = calcSheet2(app,s2_QTH)
            %             selection of sheet 2 data
            s2 = app.data2.s2;
            QTH = app.data2.s2(:,3);
            
            %           finding nearst value
            [ d,iQTH] = min( abs( QTH-s2_QTH));
            
            minQTH = app.data2.s2(iQTH,:);
            
            try
                maxQTH  = app.data2.s2(1 + iQTH,:)
            catch
                maxQTH = minQTH
            end
            
            result = [minQTH;maxQTH];
        end
        
        function result = calcSheet3(app,pr)
            %             take pressure as an input
            s3 = app.data2.s3;
            
            [ ~,coeffPressure] = min( abs( s3(:,1)-pr));
            
            result = s3(coeffPressure,:);
        end
        
        function result = calcSheet4(app,vi)
            %             take viscosity as an input
            if vi < 1500
                vi = vi;
            else
                vi = 1500;
            end


            s4 = app.data2.s4;
            coeffViscosity = find(s4(:,1) == vi);
            result = s4(coeffViscosity,:);
        end
        
        function result = calcSheet5(app,vi)
            %             taking viscosity as an input
            s5 = app.data2.s5;
            
            iVi = find(s5(:,1) == vi);
            result = s5(iVi,:);
        end
        
        function result = calcSheet6(app,RPM)
            %             taking input a RPM
            s6 = app.data2.s6;
            iRPM = find(s6(:,1) == RPM);
            result = s6(iRPM,:);
        end
        
        function result = calcQF(app,sheet2,sheet3,sheet4)
            max = sheet2(1,4);
            min = sheet2(2,4);
            
            cofP = sheet3(1,2);
            cofV = sheet4(1,2);
            
            maxQF = max * cofP * cofV;
            minQF = min * cofP * cofV;
            
            result = [minQF,maxQF]
        end
        
        function result = calcQR(app,sheet2,sheet6,QF)
            minQth = sheet2(1,3);
            maxQth = sheet2(2,3);
            
            
            N = sheet6(1); % n = RPM
            minQR = (minQth * N/1000) - QF(1);
            maxQR = (maxQth * N/1000) - QF(2);
            
            result = [minQR , maxQR];
        end
        
        function result = calcCoeffRPM(app,sheet6)
            RPM = sheet6(1);
            result = (RPM / 1500)^1.6;
        end
        
        function result = calcPp(app,sheet2,sheet5,sheet6)
            minPp = sheet2(1,5);
            maxPp = sheet2(2,5);
            
            coeffRPM = sheet6(2);
            coeffVisc = sheet5(2);
            
            minPP = minPp * coeffRPM * coeffVisc;
            maxPP = maxPp * coeffRPM * coeffVisc;
            
            result = [minPP,maxPP];
        end
        
        function result = calcPTH(app,sheet2,sheet3,sheet6)
            minQth = sheet2(1,3);
            maxQth = sheet2(2,3);
            
            deltaP = sheet3(1);
            
            N = sheet6(1);
            
            minPTH = (minQth * deltaP * N) / 27000;
            maxPTH = (maxQth * deltaP * N) / 27000;
            
            result = [minPTH,maxPTH];
        end
    
        function plotSheet3 (app,pr)
            pr = pr +2
            s3 = app.data2.s3;
            index = s3(:,1) < pr;
            filter = s3(index,:);
            plottingPoints = filter(:,1) .* filter(:,2);
            ax = app.UIAxes;
            plot(ax,plottingPoints);
            ylabel(ax,'pressure');
        end
    
        function plotSheet4(app,vi)
            s4 = app.data2.s4;
            
            index = s4(:,1) <= vi;
            filter = s4(index,:);
            
            plottingPoints = filter(:,1) .* filter(:,2);
            ax = app.UIAxes;
            plot(ax,plottingPoints);
        end
    
        function plotSheet5(app,vi)
            s5 = app.data2.s5;

            index = s5(:,1) <= vi;
            filter = s5(index,:);
            
            plottingPointes = filter(:,1) .* filter(:,2);
            ax = app.UIAxes;
            plot(ax,plottingPointes);
        end
    
        function plotSheet6(app,RPM)
            s6 = app.data2.s6;
            
            index = s6(:,1) <= RPM;
            filter = s6(index,:);
            
            plottingPoints = filter(:,1) .* filter(:,2);
            
            ax = app.UIAxes;
            plot(ax,plottingPoints);
            
        end
    
    end

    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.DisplayResult.Visible = 'off';
            app.testLabel.Visible = 'off';
            % disable
            app.ViscosityDropDown.Enable = 'off';
            app.PressurePDropDown.Enable = 'off';
            app.TotalresultsLabel.Visible = 'off';
            % defining user to do
            app.userGuideLabel.Text = 'add Flow rate to find diameter';
            
            
            %             data2 functions
            fl = 500;
            vi = 15000;
            pr = 12;
            RPM = 750;
            
            Qth = 950;
            
            sheet2 = app.calcSheet2(Qth);
            sheet3 = app.calcSheet3(pr);
            sheet4 = app.calcSheet4(vi);
            sheet5 = app.calcSheet5(vi);
            sheet6 = app.calcSheet6(RPM);
            
            QF = app.calcQF(sheet2,sheet3,sheet4);
            QR = app.calcQR(sheet2,sheet6,QF);
            CoeffRPM = app.calcCoeffRPM(sheet6);
            PP = app.calcPp(sheet2,sheet5,sheet6);
            PTH = app.calcPTH(sheet2,sheet3,sheet6);
            
            pabs = PTH + PP;
            
            app.plotSheet6(RPM);
            
        end

        % Value changed function: FlowrateQEditField
        function FlowrateQEditFieldValueChanged(app, event)
            value = app.FlowrateQEditField.Value;
            app.flowrate = value;
            
            Data = findFlowrate(app,app.flowrate,0);
            totalResults(app,size(Data,1));
            
            app.PressurePDropDown.Items = {['click to select']};
            app.PressurePDropDown.Enable = 'off';
            app.currentPrassureData = [];
            
            app.ViscosityDropDown.Items = {['click to select']};
            app.ViscosityDropDown.Enable = 'off';
            app.currentViscocityData = [];
            
            %app.ViscosityDropDown.Items =
            if ~isempty(Data) && app.flowrate ~= 0
                % updating current data
                flowratefunc(app,Data);
                
                %  for variation input
                DataVar = findFlowrate(app,app.flowrate,app.flowrateVar);
                totalResults(app,size(DataVar,1));
                flowratefunc(app,DataVar);
            else
                app.DisplayResult.Visible = 'off';
                app.ViscosityDropDown.Enable = 'off';
                app.PressurePDropDown.Enable = 'off';
                
                app.userGuideLabel.Text = 'No data Found !';
                
            end
        end

        % Value changed function: ViscosityDropDown
        function ViscosityDropDownValueChanged(app, event)
            value = str2double(app.ViscosityDropDown.Value);
            app.viscosity = value;
            Data = findViscocity(app,value,0);
            totalResults(app,size(Data,1));
            
            f = @pressurefunc;
            viscocityfunc(app,Data,f);
            
            %  for variation input
            DataVar = findViscocity(app,app.viscosity,app.viscosityVar);
            totalResults(app,size(DataVar,1));
            f = @pressurefunc;
            viscocityfunc(app,DataVar,f);
            
        end

        % Value changed function: PressurePDropDown
        function PressurePDropDownValueChanged(app, event)
            value = str2double(app.PressurePDropDown.Value);
            app.pressure = value;
            % selecting final index value
            Data = findFinalIndex(app,value,0);
            totalResults(app,size(Data,1));
            
            pressurefunc(app,Data);
            
            % for variation input
            DataVar = findFinalIndex(app,app.pressure,app.pressureVar);
            totalResults(app,size(DataVar,1));
            
            pressurefunc(app,DataVar);
        end

        % Callback function
        function okButtonPushed(app, event)
            
        end

        % Value changed function: FlowrateVariationEditField
        function FlowrateVariationEditFieldValueChanged(app, event)
            value = app.FlowrateVariationEditField.Value;
            app.flowrateVar = value;
            %             Data = plusOrMinus(app,app.flowrate,value,1,app.data.df_n);
            %             if ~isempty(Data)
            %                 flowratefunc(app,Data);
            %             end
            Data = findFlowrate(app,app.flowrate,value);
            totalResults(app,size(Data,1));
            
            flowratefunc(app,Data);
        end

        % Value changed function: ViscosityVariationEditField
        function ViscosityVariationEditFieldValueChanged(app, event)
            value = app.ViscosityVariationEditField.Value;
            app.viscosityVar = value;
            %             Data = plusOrMinus(app,app.flowrate,value,3,app.currentFlowrateData);
            %             if ~isempty(Data)
            %                  flowratefunc(app,Data);
            %             end
            Data = findViscocity(app,app.viscosity,value);
            totalResults(app,size(Data,1));
            
            f = @pressurefunc;
            viscocityfunc(app,Data,f);
        end

        % Value changed function: PressureVariationEditField
        function PressureVariationEditFieldValueChanged(app, event)
            value = app.PressureVariationEditField.Value;
            app.pressureVar = value;
            %             Data = plusOrMinus(app,app.flowrate,value,4,app.currentViscocityData);
            %             if ~isempty(Data)
            %                  flowratefunc(app,Data);
            %             end
            Data = findFinalIndex(app,app.pressure,value);
            totalResults(app,size(Data,1));
            
            pressurefunc(app,Data);
        end

        % Value changed function: dropdownmodeSwitch
        function dropdownmodeSwitchValueChanged(app, event)
            value = app.dropdownmodeSwitch.Value;
            
            if value == "On"
                app.TextFieldPanel.Visible = "Off";
            else
                app.TextFieldPanel.Visible = "On";
            end
        end

        % Value changed function: ViscosityEditField
        function ViscosityEditFieldValueChanged(app, event)
            value = app.ViscosityEditField.Value;
            app.viscosity = value;
            
            Data = findViscocity(app,value,0);
            totalResults(app,size(Data,1));
            
            f = @pressurefunc;
            viscocityfunc(app,Data,f);
            
            %  for variation input
            DataVar = findViscocity(app,app.viscosity,app.viscosityVar);
            totalResults(app,size(DataVar,1));
            f = @pressurefunc;
            viscocityfunc(app,DataVar,f);
            
        end

        % Value changed function: PressurePEditField
        function PressurePEditFieldValueChanged(app, event)
            value = app.PressurePEditField.Value;
            
            app.pressure = value;
            % selecting final index value
            Data = findFinalIndex(app,value,0);
            totalResults(app,size(Data,1));
            
            pressurefunc(app,Data);
            
            %  for variation input
            DataVar = findFinalIndex(app,app.pressure,app.pressureVar);
            totalResults(app,size(DataVar,1));
            
            pressurefunc(app,DataVar);
        end

        % Callback function: DisplayResult
        function DisplayResultCellSelection(app, event)
            eventData = event.Source.Data;
            
            selected_data = eventData(event.Indices(1),:);
            app.fl = selected_data(1);
            app.vi = selected_data(3);
            app.pr = selected_data(4);
            app.RPM = 950;
            
            Qth = app.calcQTH(app.RPM,app.fl);
            
            sheet2 = app.calcSheet2(Qth);
            sheet3 = app.calcSheet3(app.pr);
            sheet4 = app.calcSheet4(app.vi);
            sheet5 = app.calcSheet5(app.vi);
            sheet6 = app.calcSheet6(app.RPM);
            
            QF = app.calcQF(sheet2,sheet3,sheet4);
            QR = app.calcQR(sheet2,sheet6,QF);
            CoeffRPM = app.calcCoeffRPM(sheet6);
            PP = app.calcPp(sheet2,sheet5,sheet6);
            PTH = app.calcPTH(sheet2,sheet3,sheet6);
            
            pabs = PTH + PP
            
            results = [QR',[CoeffRPM;CoeffRPM],PP',PTH',pabs']
            
            app.UITableResults.Data = results;
            

            app.plotSheet3(app.pr);
            %             app.TabGroup.SelectedTab = app.Tab2
        end

        % Value changed function: SelectCurveDropDown
        function SelectCurveDropDownValueChanged(app, event)
            value = app.SelectCurveDropDown.Value;
            
            if(value == 'sheet 3')
                app.plotSheet3(app.pr);
            elseif(value == 'sheet 4')
                app.plotSheet4(app.vi);
            elseif(value == 'sheet 5')
                app.plotSheet5(app.vi)
            else
                app.plotSheet6(app.RPM)
            end
            
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.Tab = uitab(app.TabGroup);
            app.Tab.Title = 'Tab';
            app.DisplayResult = uitable(app.Tab);
            app.DisplayResult.ColumnName = {'m3/hr - Q'; 'kW - P'; 'Viscosity'; 'Pressure - bar'; 'Diameter'; 'Pitch'};
            app.DisplayResult.RowName = {};
            app.DisplayResult.CellEditCallback = createCallbackFcn(app, @DisplayResultCellSelection, true);
            app.DisplayResult.CellSelectionCallback = createCallbackFcn(app, @DisplayResultCellSelection, true);
            app.userGuideLabel = uilabel(app.Tab);
            app.userGuideLabel.Text = 'userGuide';
            app.testLabel = uilabel(app.Tab);
            app.testLabel.Text = 'test';
            app.Panel = uipanel(app.Tab);
            app.FlowrateQEditFieldLabel = uilabel(app.Panel);
            app.FlowrateQEditFieldLabel.Text = 'Flow rate - Q';
            app.FlowrateQEditField = uieditfield(app.Panel, 'numeric');
            app.FlowrateQEditField.ValueChangedFcn = createCallbackFcn(app, @FlowrateQEditFieldValueChanged, true);
            app.ViscosityDropDownLabel = uilabel(app.Panel);
            app.ViscosityDropDownLabel.Text = 'Viscosity - μ ';
            app.ViscosityDropDown = uidropdown(app.Panel);
            app.ViscosityDropDown.Items = {'click to select', 'Option 2', 'Option 3', 'Option 4'};
            app.ViscosityDropDown.ValueChangedFcn = createCallbackFcn(app, @ViscosityDropDownValueChanged, true);
            app.ViscosityDropDown.Value = 'click to select';
            app.PressurePDropDownLabel = uilabel(app.Panel);
            app.PressurePDropDownLabel.Text = 'Pressure - P ';
            app.PressurePDropDown = uidropdown(app.Panel);
            app.PressurePDropDown.Items = {'click to select', 'Option 2', 'Option 3'};
            app.PressurePDropDown.ValueChangedFcn = createCallbackFcn(app, @PressurePDropDownValueChanged, true);
            app.PressurePDropDown.Value = 'click to select';
            app.RPMDropDownLabel = uilabel(app.Panel);
            app.RPMDropDownLabel.Text = 'RPM            ';
            app.RPMDropDown = uidropdown(app.Panel);
            app.RPMDropDown.Items = {'950'};
            app.RPMDropDown.Value = '950';
            app.FlowrateVariationEditField = uieditfield(app.Panel, 'numeric');
            app.FlowrateVariationEditField.ValueChangedFcn = createCallbackFcn(app, @FlowrateVariationEditFieldValueChanged, true);
            app.Label = uilabel(app.Panel);
            app.Label.Text = '±';
            app.ViscosityVariationEditField = uieditfield(app.Panel, 'numeric');
            app.ViscosityVariationEditField.ValueChangedFcn = createCallbackFcn(app, @ViscosityVariationEditFieldValueChanged, true);
            app.Label_2 = uilabel(app.Panel);
            app.Label_2.Text = '±';
            app.PressureVariationEditField = uieditfield(app.Panel, 'numeric');
            app.PressureVariationEditField.ValueChangedFcn = createCallbackFcn(app, @PressureVariationEditFieldValueChanged, true);
            app.Label_3 = uilabel(app.Panel);
            app.Label_3.Text = '±';
            app.dropdownmodeSwitch = uiswitch(app.Panel, 'slider');
            app.dropdownmodeSwitch.ValueChangedFcn = createCallbackFcn(app, @dropdownmodeSwitchValueChanged, true);
            app.dropdownmodeSwitchLabel = uilabel(app.Panel);
            app.dropdownmodeSwitchLabel.Text = 'drop down mode';
            app.TextFieldPanel = uipanel(app.Panel);
            app.ViscosityEditFieldLabel = uilabel(app.TextFieldPanel);
            app.ViscosityEditFieldLabel.Text = 'Viscosity - μ';
            app.ViscosityEditField = uieditfield(app.TextFieldPanel, 'numeric');
            app.ViscosityEditField.ValueChangedFcn = createCallbackFcn(app, @ViscosityEditFieldValueChanged, true);
            app.PressurePEditFieldLabel = uilabel(app.TextFieldPanel);
            app.PressurePEditFieldLabel.Text = 'Pressure -  P';
            app.PressurePEditField = uieditfield(app.TextFieldPanel, 'numeric');
            app.PressurePEditField.ValueChangedFcn = createCallbackFcn(app, @PressurePEditFieldValueChanged, true);
            app.TotalresultsLabel = uilabel(app.Tab);
            app.TotalresultsLabel.Text = 'Total results';
            app.mhrLabel = uilabel(app.Tab);
            app.mhrLabel.Text = 'm³/hr';
            app.cStLabel = uilabel(app.Tab);
            app.cStLabel.Text = 'cSt';
            app.barLabel = uilabel(app.Tab);
            app.barLabel.Text = 'bar';
            app.UIAxes = uiaxes(app.Tab);
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            app.UITableResults = uitable(app.Tab);
            app.UITableResults.ColumnName = {'QR'; 'Coeff RPM'; 'PP'; 'PTH'; 'PABS'};
            app.UITableResults.RowName = {};
            app.SelectCurveDropDownLabel = uilabel(app.Tab);
            app.SelectCurveDropDownLabel.Text = 'Select Curve';
            app.SelectCurveDropDown = uidropdown(app.Tab);
            app.SelectCurveDropDown.Items = {'sheet 3', 'sheet 4', 'sheet 5', 'sheet 6'};
            app.SelectCurveDropDown.ValueChangedFcn = createCallbackFcn(app, @SelectCurveDropDownValueChanged, true);
            app.SelectCurveDropDown.Value = 'sheet 3';
            app.Tab2 = uitab(app.TabGroup);
            app.Tab2.Title = 'Tab2';
        end
    end

    methods (Access = public)

        % Construct app
        function app = app1

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end