classdef pumpSqure < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        Panel                         matlab.ui.container.Panel
        RPMLabel                      matlab.ui.control.Label
        RPMEditField                  matlab.ui.control.NumericEditField
        FlowrateLabel                 matlab.ui.control.Label
        FlowrateEditField             matlab.ui.control.NumericEditField
        ViscosityLabel                matlab.ui.control.Label
        ViscosityEditField            matlab.ui.control.NumericEditField
        PresssureLabel                matlab.ui.control.Label
        PresssureEditField            matlab.ui.control.NumericEditField
        okButton                      matlab.ui.control.Button
        mhrLabel                      matlab.ui.control.Label
        cStLabel                      matlab.ui.control.Label
        barLabel                      matlab.ui.control.Label
        UITable                       matlab.ui.control.Table
        UIAxes                        matlab.ui.control.UIAxes
        ButtonGroup                   matlab.ui.container.ButtonGroup
        GraphTypeDropDownLabel        matlab.ui.control.Label
        GraphTypeDropDown             matlab.ui.control.DropDown
        ExportgraphdataButton         matlab.ui.control.Button
        resultWarning                 matlab.ui.control.Label
        PumpSquareLTDLabel            matlab.ui.control.Label
        TwinscrewpumpselectionLabel   matlab.ui.control.Label
        DesignedbyKartikGarasiaLabel  matlab.ui.control.Label
    end


    properties (Access = private)
         data = load('data_2_sheet_all.mat');
         RPM = 0;
         fl = 0;
         vi = 0;
         pr = 0;
         selectedCol = 1;
         QR = 0;
         exportData;
         TableData;
    end

    methods (Access = private)
        
        
        % just for testing 

        function result = sheet2test(app,Qth)
            s2 = app.data.s2;
            colQth = s2(:,3);
                
            % finding the closest value
            [~,iQth] = min( abs(Qth-colQth'));
            
            Last = iQth == length(colQth);
            First = iQth == 1;
                       
    
            if First % this indicates the last element of the Qth array
                minQTH = app.data.s2(iQth,:);
                maxQTH = app.data.s2(iQth + 1,:);                
            else
                minQTH = app.data.s2(iQth - 1,:);
                maxQTH = app.data.s2(iQth,:);
            end
        % returning Qth min then Qth max
            result = [minQTH;maxQTH];
        end


        function result = sheet2(app)
            s2 = app.data.s2;
            colQth = s2(:,3);
            
            Qth = (1000 * app.fl) / app.RPM;
                
            % finding the closest value
            [d,iQth] = min( abs(Qth-colQth'));
            
            try
                minQTH = app.data.s2(iQth - 1,:);
                maxQTH = app.data.s2(iQth,:);
            catch
                minQTH = app.data.s2(iQth,:);
                maxQTH = app.data.s2(iQth + 1,:);                
            end
            
        % returning Qth min then Qth max
            result = [minQTH;maxQTH];
        end
    
    
        function result = sheet3(app)
            s3 = app.data.s3;
            colPr = s3(:,1);
            % finding the closest value
            [d,iPr] = min(abs(colPr - app.pr));

            result = s3(iPr,:);
        end
    
        function result = sheet4(app)
            s4 = app.data.s4;
            colVi = s4(:,1);
            % finding the closest value
            [d,iVi] = min(abs(colVi - app.vi));
            result = s4(iVi,:);
        end
    
        function result = sheet5(app)
            s5 = app.data.s5;
            colVi = s5(:,1);
            % finding the closest value
            [d,iVi] = min(abs(colVi - app.vi));
            result = s5(iVi,:);
        end
    
        function result = sheet6(app)
            s6 = app.data.s6;
            colRPM = s6(:,1);
            % finding the closest value
            [d,iRPM] = min(abs(colRPM - app.RPM));
            result = s6(iRPM,:);
        end
    
        function result = calcQF(app,sheet2,sheet3,sheet4)
            f = sheet2(1,4);
            coBar = sheet3(1,2);
            coVi = sheet4(1,2);
            
            result = f * coBar * coVi;
        end
    
        function result = calcQR(app,sheet2,sheet6,QF)
            QTH = sheet2(1,3);
            n = sheet6(1,1);
            result = (QTH * n)/1000 - QF;
        end
    
        function result = calcCoRPM(app)
            RPM = app.RPM;
            result = (RPM/1500)^1.6;
        end
        
        function result = caclPP(app,sheet2,sheet5,sheet6)
            pp = sheet2(1,5);
            coRPM = sheet6(1,2);
            coVi = sheet5(1,2);
            result = pp * coRPM * coVi;
        end
    
        function result = calcPTH(app,sheet2,sheet3,sheet6)
            QTH = sheet2(1,3);
            deltaP = sheet3(1,1);
            n = sheet6(1,1); % QUESTION: we can have the RPM from the user input as well which one is prefferd?
            result = (QTH * deltaP * n)/27000;
        end
    
    
        % just for testing 
        
        function result = calcParamsTest(app,index,Qth,sheet3)
            sheet2All = app.sheet2test(Qth);
            sheet2 = sheet2All(index,:); % taking 2st row by default we can adjust the Qth in the loop
            sheet3 = app.sheet3();
            sheet4 = app.sheet4();
            sheet5 = app.sheet5();
            sheet6 = app.sheet6();
            QF = app.calcQF(sheet2,sheet3,sheet4);
            QR = app.calcQR(sheet2,sheet6,QF);
            calcCoRPM = app.calcCoRPM(); % QUESTION: this variable have not used even once, why?
            PP = app.caclPP(sheet2,sheet5,sheet6);
            PTH = app.calcPTH(sheet2,sheet3,sheet6);
            PABS = PTH + PP;
            app.QR  = QR;
            
            % output of all answer in the 1 * 4 matrix [PABS,QR,Pitch,Diameter]
            pitch = sheet2(1,2);
            diameter = sheet2(1,1);
            pumpModel = sheet2(1,6);
            
            result = [PABS,QR,pitch,diameter,pumpModel,Qth]
        end
        
        
        function result = calcParams(app,index)
            sheet2All = app.sheet2();
            sheet2 = sheet2All(index,:); % taking 2st row by default we can adjust the Qth in the loop
            sheet3 = app.sheet3();
            sheet4 = app.sheet4();
            sheet5 = app.sheet5();
            sheet6 = app.sheet6();
            
            QF = app.calcQF(sheet2,sheet3,sheet4);
            QR = app.calcQR(sheet2,sheet6,QF);
            calcCoRPM = app.calcCoRPM(); % QUESTION: this variable have not used even once, why?
            PP = app.caclPP(sheet2,sheet5,sheet6);
            PTH = app.calcPTH(sheet2,sheet3,sheet6);
            PABS = PTH + PP;
            app.QR  = QR;

            % output of all answer in the 1 * 4 matrix [PABS,QR,Pitch,Diameter]
            pitch = sheet2(1,2);
            diameter = sheet2(1,1);
            pumpModel = sheet2(1,6);
            result = [PABS,QR,pitch,diameter,pumpModel];
        end
    
        function genPlot(app,index,plotSelection)
            s3 = app.data.s3;
            Qth = app.TableData(index,6);
            sheet2All = app.sheet2test(Qth);
            sheet2 = sheet2All(index,:); % taking 2nd row by default we can adjust the Qth in the loop
            sheet3 = app.sheet3();
            sheet4 = app.sheet4();
            sheet5 = app.sheet5();
            sheet6 = app.sheet6();
            PP = app.caclPP(sheet2,sheet5,sheet6);
          
            % QF QR PTH PABS need to be in the loop
            QFs = [];
            QRs = [];
            PTHs = [];
            PABSs = [];
            PumpEffs = [];
            VolEffs = [];
            Tqs = [];
            
            for pr = (s3()')
                pr = pr';
                QF = app.calcQF(sheet2,pr,sheet4);
                QR = app.calcQR(sheet2,sheet6,QF);
                PTH = app.calcPTH(sheet2,pr,sheet6);
                PABS = PTH + PP;
                volEff = app.calcVolEff(sheet2,QF);
                PumpEff = app.CalcPumpEff(PABS,pr(1),QR);
                
                Tq = app.calcTourqe(PABS,app.RPM);
                
                QRs = [QRs;QR];           
                QFs = [QFs;QF];
                PABSs = [PABSs;PABS];
                PumpEffs = [PumpEffs;PumpEff];
                VolEffs = [VolEffs;volEff];
                Tqs = [Tqs;Tq];
            end
            
            % results [n.3] matrix QRs QFs PABSs
            titles = {'pressure','QF','QR','PABS','PumpEff'};
            result = [s3(:,1), QRs, PABSs,PumpEffs,VolEffs,Tqs];
            app.exportData = result;
            
            prams = app.calcParams(app.selectedCol);
            pQR = prams(1,2);
            pPABS = prams(1,1);
            ax = app.UIAxes();
            
            [~,iPr] = min(abs(app.data.s3(:,1) - app.pr));
            
            if strcmp(plotSelection,'QR')
                plot(ax,s3(:,1),QRs);
                hold(ax,'on');
                plot(ax,sheet3(:,1),QRs(iPr),'*');
                hold(ax,'off');
            elseif strcmp(plotSelection,'PABS')
                plot(ax,s3(:,1),PABSs);
                hold(ax,'on');
                plot(ax,sheet3(:,1),PABSs(iPr),'*');
                hold(ax,'off');
            elseif strcmp(plotSelection,'PUMP Efficiency')
                plot(ax,s3(:,1),PumpEffs);
                hold(ax,'on');
                plot(ax,sheet3(:,1),PumpEffs(iPr),'*');
                hold(ax,'off');
            elseif strcmp(plotSelection,'Volumetric Efficiency')
                plot(ax,s3(:,1),VolEffs);
                hold(ax,'on');
                plot(ax,sheet3(:,1),VolEffs(iPr),'*');
                hold(ax,'off');
            else 
                plot(ax,s3(:,1),Tqs);
                hold(ax,'on');
                plot(ax,sheet3(:,1),Tqs(iPr),'*');
                hold(ax,'off');
            end
            
            xlabel(ax,"PRESSURE");
            ylabel(ax,plotSelection);
        end
        
        function result = CalcPumpEff(app,PABS,pr,fl)
            HP = (fl*pr)/(495 * 0.06);
            pumpEff = HP/PABS * 100;
            result = pumpEff;
        end     
   
        function result = calcVolEff(app,sheet2,QF)
            QTH = (sheet2(:,3) * app.RPM)/1000;
            Qact = QTH - QF;
            result = Qact/QTH;
        end
            
        function result = calcTourqe(app,PABS,RPM)
            TqUp = 60 * PABS;
            TqDown = 2 * 3.14 * RPM;
            result = TqUp/TqDown;
        end
        
        function exportResult(app)
            % todo
            A = app.exportData;
            [file,path] = uiputfile('*.csv');
            filename = fullfile(path,file);
            if filename
                csvwrite(filename,A);
            end
        end
    
        function checkAllValueIsNotNull(app)
            if(app.RPM == 0)
                app.okButton.Enable = "off";
            elseif(app.fl == 0)
                app.okButton.Enable = "off";
            elseif(app.vi == 0)
                app.okButton.Enable = "off";
            elseif(app.pr == 0)
                app.okButton.Enable = "off";
            else
                app.okButton.Enable = "on";
            end
        end
        
    end
   

    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % warning label
            app.resultWarning.Visible = "off";
            app.GraphTypeDropDown.Enable = "off";
            app.ExportgraphdataButton.Enable = "off";
        end

        % Value changed function: RPMEditField
        function RPMEditFieldValueChanged(app, event)
            value = app.RPMEditField.Value;
            app.RPM = value;
        end

        % Value changed function: FlowrateEditField
        function FlowrateEditFieldValueChanged(app, event)
            value = app.FlowrateEditField.Value;
            app.fl = value;
        end

        % Value changed function: ViscosityEditField
        function ViscosityEditFieldValueChanged(app, event)
            value = app.ViscosityEditField.Value;
            app.vi = value;
        end

        % Value changed function: PresssureEditField
        function PresssureEditFieldValueChanged(app, event)
            value = app.PresssureEditField.Value;
            app.pr = value;
        end

        % Button pushed function: okButton
        function okButtonPushed(app, event)
            plotSelection = app.GraphTypeDropDown.Value;
            
%             min = app.calcParams(1);
%             max = app.calcParams(2);
%             
%             app.QR = max(1,2);
%             
%             tableData = [min;max];

            % new changes 
            
            Qths = app.data.s2(:,3);
            
            answers = [];
            
            index = 0;
            for Qth = (Qths')
                index = index + 1;
                maxIndex = size(Qths);

                if index == 1  % first value
                    ans = app.calcParamsTest(1,Qth);
                else 
                    
                    ans = app.calcParamsTest(2,Qth);
                end
                answers = [answers;ans];
            end

            uQR = app.fl;

            index2 = 0;
            for cQR = (answers(:,2)')
                index2 = index2 + 1;
                if uQR <= cQR
                    app.resultWarning.Visible = "off";
                    if index2 == 1 % case first val
                        one = answers(index2,:);
                        two = answers(index2 + 1,:);
                    else
                        one = answers(index2 - 1,:);
                        two = answers(index2,:);
                    end
                    ANS = [one;two];
                    app.TableData = ANS;
                    app.UITable.Data = ANS(:,1:5);
                    break;
                else
                    app.resultWarning.Visible = "on";
                    if index2 == 1 % case first val
                        one = answers(index2,:);
                        two = answers(index2 + 1,:);
                    else
                        one = answers(index2 - 1,:);
                        two = answers(index2,:);
                    end
                    ANS = [one;two];
                    app.TableData = ANS;
                    app.UITable.Data = ANS(:,1:5);
                end
            end
            
            app.genPlot(app.selectedCol,plotSelection);
            
            % elableing graph ui
            app.GraphTypeDropDown.Enable = "on";
            app.ExportgraphdataButton.Enable = "on";
        end

        % Cell selection callback: UITable
        function UITableCellSelection(app, event)
             selection = event.Indices;
             app.selectedCol = selection(:,1);
             
             plotSelection = app.GraphTypeDropDown.Value;
             app.genPlot(app.selectedCol,plotSelection);
        end

        % Selection changed function: ButtonGroup
        function ButtonGroupSelectionChanged(app, event)
            selectedButton = app.ButtonGroup.SelectedObject;
            selected = selectedButton.Text;
            
            app.genPlot(app.selectedCol,selected);
        end

        % Button pushed function: ExportgraphdataButton
        function ExportgraphdataButtonPushed(app, event)
            app.exportResult();
        end

        % Value changed function: GraphTypeDropDown
        function GraphTypeDropDownValueChanged(app, event)
            value = app.GraphTypeDropDown.Value;
            app.genPlot(app.selectedCol,value);
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.Panel = uipanel(app.UIFigure);
            app.RPMLabel = uilabel(app.Panel);
            app.RPMLabel.Text = 'RPM          ';
            app.RPMEditField = uieditfield(app.Panel, 'numeric');
            app.RPMEditField.ValueChangedFcn = createCallbackFcn(app, @RPMEditFieldValueChanged, true);
            app.FlowrateLabel = uilabel(app.Panel);
            app.FlowrateLabel.Text = 'Flowrate    ';
            app.FlowrateEditField = uieditfield(app.Panel, 'numeric');
            app.FlowrateEditField.ValueChangedFcn = createCallbackFcn(app, @FlowrateEditFieldValueChanged, true);
            app.ViscosityLabel = uilabel(app.Panel);
            app.ViscosityLabel.Text = 'Viscosity    ';
            app.ViscosityEditField = uieditfield(app.Panel, 'numeric');
            app.ViscosityEditField.ValueChangedFcn = createCallbackFcn(app, @ViscosityEditFieldValueChanged, true);
            app.PresssureLabel = uilabel(app.Panel);
            app.PresssureLabel.Text = 'Presssure  ';
            app.PresssureEditField = uieditfield(app.Panel, 'numeric');
            app.PresssureEditField.ValueChangedFcn = createCallbackFcn(app, @PresssureEditFieldValueChanged, true);
            app.okButton = uibutton(app.Panel, 'push');
            app.okButton.ButtonPushedFcn = createCallbackFcn(app, @okButtonPushed, true);
            app.okButton.Text = 'ok';
            app.mhrLabel = uilabel(app.Panel);
            app.mhrLabel.Text = 'm³/hr';
            app.cStLabel = uilabel(app.Panel);
            app.cStLabel.Text = 'cSt';
            app.barLabel = uilabel(app.Panel);
            app.barLabel.Text = 'bar';
            app.UITable = uitable(app.UIFigure);
            app.UITable.ColumnName = {'PABS'; 'QR'; 'Pitch'; 'Diameter'; 'Pump Model'};
            app.UITable.RowName = {};
            app.UITable.CellSelectionCallback = createCallbackFcn(app, @UITableCellSelection, true);
            app.UIAxes = uiaxes(app.UIFigure);
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            app.ButtonGroup = uibuttongroup(app.UIFigure);
            app.ButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @ButtonGroupSelectionChanged, true);
            app.GraphTypeDropDownLabel = uilabel(app.ButtonGroup);
            app.GraphTypeDropDownLabel.Text = 'Graph Type';
            app.GraphTypeDropDown = uidropdown(app.ButtonGroup);
            app.GraphTypeDropDown.Items = {'QR', 'PABS', 'PUMP Efficiency', 'Volumetric Efficiency', 'Pump Tourqe'};
            app.GraphTypeDropDown.ValueChangedFcn = createCallbackFcn(app, @GraphTypeDropDownValueChanged, true);
            app.GraphTypeDropDown.Value = 'QR';
            app.ExportgraphdataButton = uibutton(app.UIFigure, 'push');
            app.ExportgraphdataButton.ButtonPushedFcn = createCallbackFcn(app, @ExportgraphdataButtonPushed, true);
            app.ExportgraphdataButton.Text = 'Export graph data';
            app.resultWarning = uilabel(app.UIFigure);
            app.resultWarning.Text = 'Results is lower then required flowrate (showing maximum value) ';
            app.PumpSquareLTDLabel = uilabel(app.UIFigure);
            app.PumpSquareLTDLabel.Text = 'PumpSquare LTD';
            app.TwinscrewpumpselectionLabel = uilabel(app.UIFigure);
            app.TwinscrewpumpselectionLabel.Text = 'Twin screw pump selection';
            app.DesignedbyKartikGarasiaLabel = uilabel(app.UIFigure);
            app.DesignedbyKartikGarasiaLabel.Text = 'Designed by Kartik Garasia';
        end
    end

    methods (Access = public)

        % Construct app
        function app = pumpSqure

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end